classdef MyBalance_App_20b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BalanceGUIFigure               matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        ConnectCOMPortButton           matlab.ui.control.Button
        COMPortEditFieldLabel          matlab.ui.control.Label
        COMPortEditField               matlab.ui.control.EditField
        BaudRateEditFieldLabel         matlab.ui.control.Label
        BaudRateEditField              matlab.ui.control.EditField
        DisconnectButton               matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        FWButton                       matlab.ui.control.StateButton
        BWButton                       matlab.ui.control.StateButton
        LButton                        matlab.ui.control.StateButton
        RButton                        matlab.ui.control.StateButton
        MaxSpeedKnobLabel              matlab.ui.control.Label
        MaxSpeedKnob                   matlab.ui.control.Knob
        Image                          matlab.ui.control.Image
        UseyourKeyboardArrowKeysLabel  matlab.ui.control.Label
        OrpressthebuttonsbelowwithyourmouseLabel  matlab.ui.control.Label
        MakesurethiswindowisactivebyclickingsomewherinthewindowLabel  matlab.ui.control.Label
        MaxTurnSpeedKnob               matlab.ui.control.Knob
        MaxTurnSpeedKnobLabel          matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        DataPacketSenttoDeviceLabel    matlab.ui.control.Label
        ReceiveDataPanel               matlab.ui.container.Panel
        PlotDataButton                 matlab.ui.control.Button
        SamplestoPlotLabel             matlab.ui.control.Label
        SamplestoPlotEditField         matlab.ui.control.EditField
        NumberofVariablesEditFieldLabel  matlab.ui.control.Label
        NumberofVariablesEditField     matlab.ui.control.EditField
        StopButton                     matlab.ui.control.Button
        AfterStopPresseddataisstoredinvariablesfdatLabel  matlab.ui.control.Label
        GainTuningPanel                matlab.ui.container.Panel
        SendDataButton                 matlab.ui.control.Button
        K1SpinnerLabel                 matlab.ui.control.Label
        K1Spinner                      matlab.ui.control.Spinner
        K2SpinnerLabel                 matlab.ui.control.Label
        K2Spinner                      matlab.ui.control.Spinner
        K3SpinnerLabel                 matlab.ui.control.Label
        K3Spinner                      matlab.ui.control.Spinner
        K4SpinnerLabel                 matlab.ui.control.Label
        K4Spinner                      matlab.ui.control.Spinner
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        COMdevice;
        FWBWButton=0; % Description
        LRButton=0; % Description
        COMconnected=0; % Description
        LastKeyPress;% Description
        Fig_timer % Description
        Fig_AUTOSCALEKEYPRESS % Description
        Fig_AUTOSCALECHECK % Description
        Fig_STOPKEYPRESS % Description
    end
    
    methods (Access = private)
        
        function [fh dh]=InitFig(app,Ncx)
            %             %% initizlize a figure for plotting data:
            fh=figure;
            app.Fig_AUTOSCALEKEYPRESS=1;app.Fig_AUTOSCALECHECK=1;app.Fig_STOPKEYPRESS=0;
            
            axes('Parent',fh,'FontSize',14);
            set(fh,'toolbar','figure');
            
            uicontrol('Style', 'pushbutton', 'String', 'Stop',...
                'Units','normalized','Position', [0 0 .1 .05],...
                'Callback', @app.Fig_stopbutton_fcn);
            
            % uicontrol('Style', 'pushbutton', 'String', 'Byte Adjust',...
            %     'Units','normalized','Position', [.1 0 .2 .05],...
            %     'Callback', @byteadjust);
            
            uicontrol('Style', 'pushbutton', 'String', 'Manual AutoScale',...
                'Units','normalized','Position', [.1 0 .2 .05],...
                'Callback', @app.Fig_AutoScaleButton_fcn);
            hchkbox=uicontrol('Style', 'checkbox', 'String', 'AutoScale',...
                'Units','normalized','Position', [.3 0 .3 .05],...
                'Callback', @app.Fig_AutoScaleCheckbox_fcn,'Value',1);
            
            if(Ncx>1)
                dh=plot(zeros(2,Ncx),'LineWidth',3);
                switch Ncx
                    case 1
                        legend('data 1');
                    case 2
                        legend('data 1','data 2');
                    case 3
                        legend('data 1','data 2','data 3');
                    case 4
                        legend('data 1','data 2','data 3','data 4');
                    case 5
                        legend('data 1','data 2','data 3','data 4','data 5');
                    case 6
                        legend('data 1','data 2','data 3','data 4','data 5',' data 6');
                    case 7
                        legend('data 1','data 2','data 3','data 4','data 5', 'data 6','data 7');
                    case 8
                        legend('data 1','data 2','data 3','data 4','data 5', 'data 6','data 7','data 8');
                    case 9
                        legend('data 1','data 2','data 3','data 4','data 5', 'data 6','data 7','data 8','data 9');
                end
                
            else
                dh=plot(0,'r','LineWidth',4);
            end
            
            ylim([-1 1]) % for x in mm
            drawnow
        end
        
        function StopFcn(app,mTimer,~)
            % disp('stop function executed');
            try
                stop(timerfindall);
                delete(timerfindall);
            catch, end
        end
        
        function StartFcn(app,mTimer,~)
            disp(['StartFcn',' executed '...
                datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF')]);
        end
        
        function TimerFcn(app,mTimer,~)
            disp('Plotting Timer Function Started')
            
            COMport=mTimer.UserData.COMport;
            DataType=mTimer.UserData.DataType;
            NumSamples=mTimer.UserData.NumSamples;
            BaudRate=mTimer.UserData.BaudRate;
            Ncx=mTimer.UserData.Ncx;
            
            close all
            
            disp(['Model: ' gcs ])
            disp(['COMport: ' COMport ' DataType: ' DataType ' Number Samples:' num2str(NumSamples)]);
            disp(' ');
            disp(['-------------------- Opening ' COMport '  please wait up to 30 seconds ------------------'])
            disp(' ');
            
            % Create the COMdevice in the base workspace if id is not already open:
            if(app.COMconnected)
                disp('COM port is already open')
                % flush the data that alreay exists:
                flush(app.COMdevice, "input");
            else
                app.COMdevice=serialport(COMport,BaudRate);
                app.COMconnected=1;app.ConnectCOMPortButton.BackgroundColor=[0 1 0];
                disp('connected')
                
                syncstring='***Data Start***';
                
                disp(['syncstring: ' syncstring])
                
                %% find start of data by waiting for ***starting the model***
                istring=[];  % initial string to find/sync start of data
                for i=1:60
                    d1=char(read(app.COMdevice, 1, 'uint8'));
                    istring=[istring d1];
                    % if you find the complete starting string
                    % ready 2 bytes then move on
                    % only data is following
                    istring_found=strfind(istring,syncstring);     %   added to SerialPlot on 9/18/2018 to replicate '***starting the model***' in pre 2017 versions
                end
                disp(['istring: ' istring])
            end
            
            % initialize figure - it is faster to update
            % figure data thean the GUI figure
            [fh dh]=app.InitFig(Ncx); app.Fig_AUTOSCALECHECK=1;
            
            % data storage variable:
            fdat=[]; datNumSamples=[];
            
            %% Read data, update graph, store data
            for k=1:1e5
                
                %% Read and store data
                % assume your computer can execute this loop faster than data is
                % sent to the seria port:
                Bytes_Available=app.COMdevice.NumBytesAvailable;
                Data_Rows_Available=floor(Bytes_Available/(4*Ncx));
                
          
                for i=1:Data_Rows_Available
                    d=read(app.COMdevice,Ncx,'single');
                    
                    % Check data integrity & flush the buffer if data is out of bounds:
                    nd=norm(d);data_out_bounds = or(nd>1e6, and(nd>0,nd<1e-8)); %if(data_out_bounds), keyboard, end
                    if(data_out_bounds)
                        for i=1:3 % try 3 times
                            if(data_out_bounds)
                                
                                disp('main data out of bounds - flushing buffer'),  %if(data_out_bounds), keyboard, end
                                disp(['attempt: ', num2str(i), ' data norm: ', num2str(nd), ' data: ', num2str(d)]);
                                flush(app.COMdevice, "input");%evalin('base','flush(COMdevice,"input")'); %d1=evalin('base',['read(COMdevice,1,''uint8'');']);%char(read(app.COMdevice, 1, 'uint8'));
                                d=read(app.COMdevice,Ncx,'single');
                                nd=norm(d);data_out_bounds = or(nd>1e8, and(nd>0,nd<1e-8)); %if(data_out_bounds), keyboard, end
                            else
                                disp('main data sychronized')
                                break;
                            end
                        end
                    end
                    
                    fdat=[fdat;d];
                end


                %% Plotting Tasks: %if(1)  % if(~DataOnlyCheck)
                %% Adjust the plotting data buffer:
                if(length(fdat)>NumSamples)
                    datNumSamples=(fdat(end-NumSamples:end,:));
                else
                    datNumSamples=fdat;
                end
                
                %% handle figure activities:
                for numxc=1:Ncx
                    % Update each channelof data on the graph
                    set(dh(numxc),'Xdata', 1:size(datNumSamples,1), 'Ydata',datNumSamples(:,numxc));
                end
                
                % Autoscale up to the NumSammples window size, then only autoscale with button
                % press:  (autoscale until the figure is filled with data)
                if(length(fdat)<NumSamples)
                    app.AutoScale(datNumSamples,NumSamples);
                else
                    if(app.Fig_AUTOSCALECHECK)
                        app.AutoScale(datNumSamples,NumSamples);
                        %disp('autoscaling')
                    end
                end
                
                if(app.Fig_AUTOSCALEKEYPRESS)
                    app.AutoScale(datNumSamples,NumSamples);
                    app.Fig_AUTOSCALEKEYPRESS=0;
                end
                
                drawnow
                
                if(app.Fig_STOPKEYPRESS),
                    disp('Plotting Stopped')
                    disp('current data exported to variables: (use plot(fdat) )')
                    disp('  fdat and wdat')
                    disp('in the workspace and saved to file: (use load fdat )')
                    disp('  fdat.mat')
                    close all
                    break;
                end


            end
            
            save 'fdat' fdat
            assignin('base','fdat',fdat)
            assignin('base', 'wdat',datNumSamples)
            stop(app.Fig_timer);delete(app.Fig_timer);
            
            try,
                %    fclose(COMdevice), delete(COMdevice), clear s
                %    evalin('base','if(exist(''COMdevice'')), disp(''Closing COM port''),clear(''COMdevice''),end')
            catch, end
            
        end
        
        function AutoScale(app,datNumSamples, NumSamples)
            %[min(datNumSamples)  max(datNumSamples)]
            %if(max(datNumSamples) > 0  && min(datNumSamples) <= max(datNumSamples) )
            % disp('adj y axis')
            minD=min(min(datNumSamples));
            maxD=max(max(datNumSamples));
            diffD=maxD-minD;
            if(diffD>0)
                ylim([minD, maxD]);
            else
                ylim([minD-1, maxD+1]);
            end
            xlim([0 length(datNumSamples)]);
            %end
        end
        
        function SerialPlotTimer(app,COMport, DataType, NumSamples, BaudRate, Ncx)
            
            %global COMdevice
            app.Fig_timer = timer;
            app.Fig_timer.StartFcn = @app.StartFcn;
            app.Fig_timer.TimerFcn = @app.TimerFcn;
            app.Fig_timer.StopFcn = @app.StopFcn;
            % t.Period = 0;
            % t.busymode='error';
            app.Fig_timer.StartDelay=.1;  % provide enough delay to not block the callback.
            % t.TasksToExecute = 2;
            app.Fig_timer.ExecutionMode = 'singleShot';
            app.Fig_timer.UserData.COMport=COMport;
            app.Fig_timer.UserData.DataType=DataType;
            app.Fig_timer.UserData.NumSamples=NumSamples;
            app.Fig_timer.UserData.BaudRate=BaudRate;
            app.Fig_timer.UserData.Ncx=Ncx;
            start(app.Fig_timer)
        end
        
        function Fig_stopbutton_fcn(app, ~, event)
            % if the stop button is pressed
            % assign stopkeypress high to stop taking data
            % assignin('caller','stopkeypress',1)
            app.Fig_STOPKEYPRESS=1;
            %% CloseInstObjects
        end
        
        function Fig_AutoScaleButton_fcn(app, ~, event)
            %assignin('caller','AutoScalekeypress',1)
            app.Fig_AUTOSCALEKEYPRESS=1;
        end
        
        function Fig_AutoScaleCheckbox_fcn(app, ~, event)
            %assignin('caller','AutoScaleCheck',event.Source.Value)
            app.Fig_AUTOSCALECHECK=event.Source.Value;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            data2send=[0 0 0 0 0 0];
            app.UITable.Data=data2send;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.BalanceGUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {556, 556};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {197, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: ConnectCOMPortButton
        function ConnectCOMPortButtonPushed(app, event)
            
            %COMport='COM10';BaudRate=9600;   % BT
            COMport=['COM' num2str(app.COMPortEditField.Value)];
            BaudRate=str2num(app.BaudRateEditField.Value);
            %             COMport='COM21';BaudRate=115200; % USB
            if(app.COMconnected)
                disp('COM already connected')
            else
                app.COMdevice=serialport(COMport,BaudRate);
                app.COMconnected=1;app.ConnectCOMPortButton.BackgroundColor=[0 1 0];
                disp('connected')
            end
            
        end

        % Callback function: K1Spinner, K1Spinner, K2Spinner, 
        % K2Spinner, K3Spinner, K3Spinner, K4Spinner, K4Spinner, 
        % SendDataButton
        function SendDataButtonPushed(app, event)
            % This function is also called whenever a arrow key is pressed
            % forward/backward and left/right speed are controlled by
            % multipling/dividing the last two values sent
            % currently left and right turns are not affected by max speed
            % knob - if you want it to effect the speeds just multiply
            % similar to how the speed is multiplied
            if(app.COMconnected)
                %disp('COM already connected')
                data2send=[app.K1Spinner.Value app.K2Spinner.Value app.K3Spinner.Value app.K4Spinner.Value app.FWBWButton*app.MaxSpeedKnob.Value/100*3.2 app.MaxTurnSpeedKnob.Value*app.LRButton/100*4];
                app.UITable.Data=data2send;
                %disp( [app.K1Spinner.Value app.K2Spinner.Value app.K3Spinner.Value app.K4Spinner.Value app.FWBWButton*app.MaxSpeedKnob.Value/100*2 app.MaxTurnSpeedKnob.Value*app.LRButton/100*2.5])
                write(app.COMdevice,data2send , "single");
            else
            end
            
        end

        % Key press function: BalanceGUIFigure
        function BalanceGUIFigureKeyPress(app, event)
            
            key = event.Key;
            %disp(['Key Pressed: ' key]); %debug
            switch key
                case app.LastKeyPress
                    %disp('already pressed - skipping')
                case 'uparrow'
                    %disp('up')
                    app.FWBWButton = 1; SendDataButtonPushed(app,event);app.FWButton.BackgroundColor=[0 1 0];
                case 'downarrow'
                    %disp('down')
                    app.FWBWButton = -1; SendDataButtonPushed(app,event);app.BWButton.BackgroundColor=[0 1 0];
                case 'leftarrow'
                    app.LRButton = 1; SendDataButtonPushed(app,event);app.LButton.BackgroundColor=[0 1 0];
                    %disp('left')
                case 'rightarrow'
                    %disp('right')
                    app.LRButton = -1; SendDataButtonPushed(app,event);app.RButton.BackgroundColor=[0 1 0];
            end
            app.LastKeyPress=key;
            
        end

        % Value changed function: BWButton
        function BWButtonValueChanged(app, event)
            app.FWBWButton = -app.BWButton.Value;
            SendDataButtonPushed(app,event);
        end

        % Value changed function: FWButton
        function FWButtonValueChanged(app, event)
            app.FWBWButton = app.FWButton.Value;
            SendDataButtonPushed(app,event);
        end

        % Value changed function: LButton
        function LButtonValueChanged(app, event)
            app.LRButton = -app.LButton.Value;
            SendDataButtonPushed(app,event);
        end

        % Value changed function: RButton
        function RButtonValueChanged(app, event)
            app.LRButton = app.RButton.Value;
            SendDataButtonPushed(app,event);
        end

        % Key release function: BalanceGUIFigure
        function BalanceGUIFigureKeyRelease(app, event)
            key = event.Key;
            % disp(['Key Released : ' key]); % debug
            switch key
                case 'uparrow'
                    % disp('up')
                    app.FWBWButton = 0; SendDataButtonPushed(app,event);app.FWButton.BackgroundColor=[0.96,0.96,0.96];
                case 'downarrow'
                    % disp('down')
                    app.FWBWButton = 0; SendDataButtonPushed(app,event); app.BWButton.BackgroundColor=[0.96,0.96,0.96];
                case 'leftarrow'
                    app.LRButton = 0; SendDataButtonPushed(app,event); app.LButton.BackgroundColor=[0.96,0.96,0.96];
                    % disp('left')
                case 'rightarrow'
                    % disp('right')
                    app.LRButton = 0; SendDataButtonPushed(app,event); app.RButton.BackgroundColor=[0.96,0.96,0.96];
            end
            app.LastKeyPress=[];
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.Fig_STOPKEYPRESS=1;
            % wait for the timer to finish & data saved before closing the
            % com port
            pause(.1);
            
            delete(app.COMdevice);app.COMconnected=0;app.ConnectCOMPortButton.BackgroundColor=[1 1 1];
        end

        % Button pushed function: PlotDataButton
        function PlotDataButtonPushed(app, event)
            COMport=['COM' num2str(app.COMPortEditField.Value)];
            BaudRate=str2num(app.BaudRateEditField.Value);
            app.SerialPlotTimer(COMport,'single',str2num(app.SamplestoPlotEditField.Value),BaudRate,str2num(app.NumberofVariablesEditField.Value));
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.Fig_stopbutton_fcn(app,event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BalanceGUIFigure = uifigure('Visible', 'off');
            app.BalanceGUIFigure.Name = 'Balance Control GUI';
            app.BalanceGUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.BalanceGUIFigure.KeyPressFcn = createCallbackFcn(app, @BalanceGUIFigureKeyPress, true);
            app.BalanceGUIFigure.KeyReleaseFcn = createCallbackFcn(app, @BalanceGUIFigureKeyRelease, true);
            app.GridLayout = uigridlayout(app.BalanceGUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Connect to System';
            app.ConnectCOMPortButton = uibutton(app.LeftPanel, 'push');
            app.ConnectCOMPortButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectCOMPortButtonPushed, true);
            app.ConnectCOMPortButton.Text = 'Connect COM Port';
            app.COMPortEditFieldLabel = uilabel(app.LeftPanel);
            app.COMPortEditFieldLabel.Text = 'COM Port';
            app.COMPortEditField = uieditfield(app.LeftPanel, 'text');
            app.COMPortEditField.Value = '6';
            app.BaudRateEditFieldLabel = uilabel(app.LeftPanel);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRateEditField = uieditfield(app.LeftPanel, 'text');
            app.BaudRateEditField.Value = '115200';
            app.DisconnectButton = uibutton(app.LeftPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'Button Control';
            app.FWButton = uibutton(app.RightPanel, 'state');
            app.FWButton.ValueChangedFcn = createCallbackFcn(app, @FWButtonValueChanged, true);
            app.FWButton.Text = 'FW';
            app.BWButton = uibutton(app.RightPanel, 'state');
            app.BWButton.ValueChangedFcn = createCallbackFcn(app, @BWButtonValueChanged, true);
            app.BWButton.Text = 'BW';
            app.LButton = uibutton(app.RightPanel, 'state');
            app.LButton.ValueChangedFcn = createCallbackFcn(app, @LButtonValueChanged, true);
            app.LButton.Text = 'L';
            app.RButton = uibutton(app.RightPanel, 'state');
            app.RButton.ValueChangedFcn = createCallbackFcn(app, @RButtonValueChanged, true);
            app.RButton.Text = 'R';
            app.MaxSpeedKnobLabel = uilabel(app.RightPanel);
            app.MaxSpeedKnobLabel.Text = 'Max Speed';
            app.MaxSpeedKnob = uiknob(app.RightPanel, 'continuous');
            app.MaxSpeedKnob.Value = 15;
            app.Image = uiimage(app.RightPanel);
            app.Image.Enable = 'off';
            app.Image.ImageSource = 'Keys.png';
            app.UseyourKeyboardArrowKeysLabel = uilabel(app.RightPanel);
            app.UseyourKeyboardArrowKeysLabel.Text = 'Use your Keyboard Arrow Keys!';
            app.OrpressthebuttonsbelowwithyourmouseLabel = uilabel(app.RightPanel);
            app.OrpressthebuttonsbelowwithyourmouseLabel.Text = 'Or press the buttons below with your mouse';
            app.MakesurethiswindowisactivebyclickingsomewherinthewindowLabel = uilabel(app.RightPanel);
            app.MakesurethiswindowisactivebyclickingsomewherinthewindowLabel.Text = {'Make sure this window is active by clicking somewher in the window'; ''};
            app.MaxTurnSpeedKnob = uiknob(app.RightPanel, 'continuous');
            app.MaxTurnSpeedKnob.Value = 15;
            app.MaxTurnSpeedKnobLabel = uilabel(app.RightPanel);
            app.MaxTurnSpeedKnobLabel.Text = 'Max Turning Speed';
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'K1'; 'K2'; 'K3'; 'K4'; 'FWD'; 'LR'};
            app.UITable.RowName = {};
            app.DataPacketSenttoDeviceLabel = uilabel(app.RightPanel);
            app.DataPacketSenttoDeviceLabel.Text = 'Data Packet Sent to Device:';
            app.ReceiveDataPanel = uipanel(app.BalanceGUIFigure);
            app.ReceiveDataPanel.Title = 'Receive Data';
            app.PlotDataButton = uibutton(app.ReceiveDataPanel, 'push');
            app.PlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataButtonPushed, true);
            app.PlotDataButton.Text = 'Plot Data';
            app.SamplestoPlotLabel = uilabel(app.ReceiveDataPanel);
            app.SamplestoPlotLabel.Text = 'Samples to Plot';
            app.SamplestoPlotEditField = uieditfield(app.ReceiveDataPanel, 'text');
            app.SamplestoPlotEditField.Value = '500';
            app.NumberofVariablesEditFieldLabel = uilabel(app.ReceiveDataPanel);
            app.NumberofVariablesEditFieldLabel.Text = 'Number of Variables';
            app.NumberofVariablesEditField = uieditfield(app.ReceiveDataPanel, 'text');
            app.NumberofVariablesEditField.Value = '2';
            app.StopButton = uibutton(app.ReceiveDataPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.AfterStopPresseddataisstoredinvariablesfdatLabel = uilabel(app.ReceiveDataPanel);
            app.AfterStopPresseddataisstoredinvariablesfdatLabel.Text = 'After Stop Pressed data is stored in variables fdat';
            app.GainTuningPanel = uipanel(app.BalanceGUIFigure);
            app.GainTuningPanel.Title = 'Gain Tuning';
            app.SendDataButton = uibutton(app.GainTuningPanel, 'push');
            app.SendDataButton.ButtonPushedFcn = createCallbackFcn(app, @SendDataButtonPushed, true);
            app.SendDataButton.Text = 'Send Data';
            app.K1SpinnerLabel = uilabel(app.GainTuningPanel);
            app.K1SpinnerLabel.Text = 'K1';
            app.K1Spinner = uispinner(app.GainTuningPanel);
            app.K1Spinner.Step = 0.2;
            app.K1Spinner.ValueChangingFcn = createCallbackFcn(app, @SendDataButtonPushed, true);
            app.K1Spinner.Limits = [0 100];
            app.K1Spinner.ValueDisplayFormat = '%5.2f';
            app.K1Spinner.ValueChangedFcn = createCallbackFcn(app, @SendDataButtonPushed, true);
            app.K1Spinner.Value = 7.7346;
            app.K2SpinnerLabel = uilabel(app.GainTuningPanel);
            app.K2SpinnerLabel.Text = 'K2';
            app.K2Spinner = uispinner(app.GainTuningPanel);
            app.K2Spinner.Step = 0.1;
            app.K2Spinner.ValueChangingFcn = createCallbackFcn(app, @SendDataButtonPushed, true);
            app.K2Spinner.Limits = [0 10];
            app.K2Spinner.ValueDisplayFormat = '%5.2f';
            app.K2Spinner.ValueChangedFcn = createCallbackFcn(app, @SendDataButtonPushed, true);
            app.K2Spinner.Value = 0.4294;
            app.K3SpinnerLabel = uilabel(app.GainTuningPanel);
            app.K3SpinnerLabel.Text = 'K3';
            app.K3Spinner = uispinner(app.GainTuningPanel);
            app.K3Spinner.Step = 0.02;
            app.K3Spinner.ValueChangingFcn = createCallbackFcn(app, @SendDataButtonPushed, true);
            app.K3Spinner.Limits = [0 10];
            app.K3Spinner.ValueDisplayFormat = '%5.2f';
            app.K3Spinner.ValueChangedFcn = createCallbackFcn(app, @SendDataButtonPushed, true);
            app.K4SpinnerLabel = uilabel(app.GainTuningPanel);
            app.K4SpinnerLabel.Text = 'K4';
            app.K4Spinner = uispinner(app.GainTuningPanel);
            app.K4Spinner.Step = 0.02;
            app.K4Spinner.ValueChangingFcn = createCallbackFcn(app, @SendDataButtonPushed, true);
            app.K4Spinner.Limits = [0 10];
            app.K4Spinner.ValueDisplayFormat = '%5.2f';
            app.K4Spinner.ValueChangedFcn = createCallbackFcn(app, @SendDataButtonPushed, true);
            app.K4Spinner.Value = 0.32;
            % Show the figure after all components are created
            app.BalanceGUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyBalance_App_20b

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BalanceGUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BalanceGUIFigure)
        end
    end
end